classdef P5_109652042 < matlab.apps.AppBase
    %P5_109652042
    %   Name: 羅宥均
    %   ID: 109652042

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SaveCollageButton        matlab.ui.control.Button
        UpdateButton             matlab.ui.control.Button
        HeightEditField          matlab.ui.control.NumericEditField
        HeightEditFieldLabel     matlab.ui.control.Label
        WidthEditField           matlab.ui.control.NumericEditField
        WidthEditFieldLabel      matlab.ui.control.Label
        PositionYEditField       matlab.ui.control.NumericEditField
        PositionYEditFieldLabel  matlab.ui.control.Label
        PositionXEditField       matlab.ui.control.NumericEditField
        PositionXEditFieldLabel  matlab.ui.control.Label
        DownButton               matlab.ui.control.Button
        UpButton                 matlab.ui.control.Button
        DeleteButton             matlab.ui.control.Button
        AddButton                matlab.ui.control.Button
        FileListBox              matlab.ui.control.ListBox
        FileListBoxLabel         matlab.ui.control.Label
        ImageCollagePanel        matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties (Non-UI components)
    properties (Access = private)
        CollageImage      % 目前拼貼畫布
        ImageList         % 儲存加載的圖片資訊
    end
    
    % Helper methods
    methods (Access = private)
        function outputImage = InsertImage(~, baseImage, insertImage, position)
            % 將圖片插入畫布的輔助函數
            [h, w, ~] = size(insertImage);
            x = position(1);
            y = position(2);
            outputImage = baseImage;
            outputImage(y:y+h-1, x:x+w-1, :) = insertImage;
        end

        function updateAxesSize(app)
            % 確保 UIAxes 填滿整個 Panel
            panelSize = app.ImageCollagePanel.Position;
            app.UIAxes.Position = [0, 0, panelSize(3), panelSize(4)];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 初始化畫布
            app.CollageImage = uint8(255 * ones(500, 800, 3)); % 預設白色背景
            imshow(app.CollageImage, 'Parent', app.UIAxes);

            % 強制調整 UIAxes 的顯示範圍
            axis(app.UIAxes, 'tight'); % 自動匹配影像大小
            app.UIAxes.XTick = []; % 隱藏 X 軸刻度
            app.UIAxes.YTick = []; % 隱藏 Y 軸刻度
            app.UIAxes.XColor = 'none'; % 隱藏 X 軸線
            app.UIAxes.YColor = 'none'; % 隱藏 Y 軸線

            app.ImageList = struct('FileName', {}, 'ImageData', {}, ...
                                    'Position', {}, 'Size', {});
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg', 'Image Files'}, 'Select Image');
            if isequal(file, 0)
                return;
            end
            fullPath = fullfile(path, file);
            img = imread(fullPath);
        
            % 初始化影像屬性
            defaultSize = size(img);
            app.ImageList(end+1) = struct('FileName', file, 'ImageData', img, ...
                                          'Position', [1, 1], 'Size', [defaultSize(1), defaultSize(2)]);
        
            % 更新 ListBox
            app.FileListBox.Items = {app.ImageList.FileName}; % 更新顯示的名稱
            app.FileListBox.ItemsData = 1:length(app.ImageList); % 使用索引作為 ItemsData
            app.FileListBox.Value = length(app.ImageList); % 預設選中新項目
            
            % 初次加入時直接更新畫布
            app.update_output();
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            idx = app.FileListBox.Value;
            if isempty(idx)
                return;
            end
            app.ImageList(idx) = [];
            app.FileListBox.Items = {app.ImageList.FileName};
            app.update_output(); % 刪除後更新畫布
        end

        % Button pushed function: UpdateButton
        function update_output(app, event)
            % 顯示加載動畫
            % h = waitbar(0, 'Updating Collage...');  % 顯示等待進度條
            app.CollageImage = uint8(255 * ones(500, 800, 3));  % 清空畫布
            for i = 1:length(app.ImageList)
                img = app.ImageList(i).ImageData;
                position = app.ImageList(i).Position;
                size = app.ImageList(i).Size;
                resizedImg = imresize(img, size);
                app.CollageImage = InsertImage(app, app.CollageImage, resizedImg, position);
                
                % 更新進度條
                % waitbar(i / length(app.ImageList), h, sprintf('Updating %d of %d...', i, length(app.ImageList)));
            end
            imshow(app.CollageImage, 'Parent', app.UIAxes);
            % close(h);  % 關閉進度條
        end

        % Button pushed function: SaveCollageButton
        function SaveCollageButtonPushed(app, event)
            [file, path] = uiputfile({'*.jpg', 'Image Files'}, 'Save Collage');
            if isequal(file, 0)
                return;
            end
            imwrite(app.CollageImage, fullfile(path, file));
        end

        % Value changed function: FileListBox
        function FileListBoxValueChanged(app, event)
            idx = app.FileListBox.Value; % 獲取選中影像的索引
            if isempty(idx) || idx > length(app.ImageList)
                return; % 若索引無效則直接退出
            end
            
            % 更新編輯框顯示的屬性值
            selectedImage = app.ImageList(idx);
            app.PositionXEditField.Value = selectedImage.Position(1);
            app.PositionYEditField.Value = selectedImage.Position(2);
            app.WidthEditField.Value = selectedImage.Size(2);
            app.HeightEditField.Value = selectedImage.Size(1);

            % 清除之前的矩形框
            hold(app.UIAxes, 'on');
            cla(app.UIAxes);  % 清除畫布上的所有內容
            app.update_output();

            % 在畫布上繪製矩形框
            hold(app.UIAxes, 'on');
            rectangle(app.UIAxes, 'Position', [selectedImage.Position, selectedImage.Size(2), selectedImage.Size(1)], ...
                'EdgeColor', 'r', 'LineWidth', 2, 'LineStyle', '-');  % 用紅色實線框住選中的影像
            hold(app.UIAxes, 'off');
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            idx = app.FileListBox.Value;
            if isempty(idx) || idx == 1
                return; % 如果已經是第一個，無法再向上移動
            end
            
            % 交換影像順序
            temp = app.ImageList(idx);
            app.ImageList(idx) = app.ImageList(idx - 1);
            app.ImageList(idx - 1) = temp;
            
            % 更新 FileListBox 並保持選中
            app.FileListBox.Items = {app.ImageList.FileName};
            app.FileListBox.Value = idx - 1;
            
            % 更新畫布
            app.update_output();
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            idx = app.FileListBox.Value;
            if isempty(idx) || idx == length(app.ImageList)
                return; % 如果已經是最後一個，無法再向下移動
            end
            
            % 交換影像順序
            temp = app.ImageList(idx);
            app.ImageList(idx) = app.ImageList(idx + 1);
            app.ImageList(idx + 1) = temp;
            
            % 更新 FileListBox 並保持選中
            app.FileListBox.Items = {app.ImageList.FileName};
            app.FileListBox.Value = idx + 1;
            
            % 更新畫布
            app.update_output();
        end

        % Value changed function: HeightEditField, PositionXEditField, 
        % ...and 2 other components
        function EditBoxValueChanged(app, event)
            idx = app.FileListBox.Value;
            if isempty(idx)
                return;
            end
            
            % 更新選中影像的屬性
            app.ImageList(idx).Position = [app.PositionXEditField.Value, app.PositionYEditField.Value];
            app.ImageList(idx).Size = [app.HeightEditField.Value, app.WidthEditField.Value];
            
            % 即時更新拼貼畫布
            app.update_output();

            % 在畫布上高亮顯示更新後的影像位置
            selectedImage = app.ImageList(idx);

            % 清除之前的矩形框
            hold(app.UIAxes, 'on');
            cla(app.UIAxes);  % 清除畫布上的所有內容
            app.update_output();

            % 在畫布上繪製新的矩形框
            rectangle(app.UIAxes, 'Position', [selectedImage.Position, selectedImage.Size(2), selectedImage.Size(1)], ...
                'EdgeColor', 'g', 'LineWidth', 2, 'LineStyle', '-');  % 用綠色實線框住已更新的影像
            hold(app.UIAxes, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageCollagePanel = uipanel(app.UIFigure);
            app.ImageCollagePanel.Title = 'Image Collage';
            app.UIAxes = uiaxes(app.ImageCollagePanel);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.FileListBoxLabel = uilabel(app.UIFigure);
            app.FileListBoxLabel.Text = 'File';
            app.FileListBox = uilistbox(app.UIFigure);
            app.FileListBox.Items = {};
            app.FileListBox.ValueChangedFcn = createCallbackFcn(app, @FileListBoxValueChanged, true);
            app.FileListBox.Value = {};
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.PositionXEditFieldLabel = uilabel(app.UIFigure);
            app.PositionXEditFieldLabel.Text = 'PositionX';
            app.PositionXEditField = uieditfield(app.UIFigure, 'numeric');
            app.PositionXEditField.ValueChangedFcn = createCallbackFcn(app, @EditBoxValueChanged, true);
            app.PositionYEditFieldLabel = uilabel(app.UIFigure);
            app.PositionYEditFieldLabel.Text = 'PositionY';
            app.PositionYEditField = uieditfield(app.UIFigure, 'numeric');
            app.PositionYEditField.ValueChangedFcn = createCallbackFcn(app, @EditBoxValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @EditBoxValueChanged, true);
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @EditBoxValueChanged, true);
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @update_output, true);
            app.UpdateButton.Text = 'Update';
            app.SaveCollageButton = uibutton(app.UIFigure, 'push');
            app.SaveCollageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCollageButtonPushed, true);
            app.SaveCollageButton.Text = 'SaveCollage';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P5_109652042

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end